classdef GUI_indivParameters_basicAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConnectivityButton            matlab.ui.control.Button
        NetworkburstAnalysisButton    matlab.ui.control.Button
        BurstAnalysisButton           matlab.ui.control.Button
        SheetnameBin                  matlab.ui.control.EditField
        SheetnameforbinningLabel      matlab.ui.control.Label
        SpikeAnalysisButton           matlab.ui.control.Button
        GroupingPanel                 matlab.ui.container.Panel
        Group12                       matlab.ui.control.EditField
        Group12Label                  matlab.ui.control.Label
        Group11                       matlab.ui.control.EditField
        Group11Label                  matlab.ui.control.Label
        Group10                       matlab.ui.control.EditField
        Group10Label                  matlab.ui.control.Label
        Group9                        matlab.ui.control.EditField
        Group9Label                   matlab.ui.control.Label
        Loadgrouping                  matlab.ui.control.Button
        Group8                        matlab.ui.control.EditField
        Group8Label                   matlab.ui.control.Label
        Group7                        matlab.ui.control.EditField
        Group7Label                   matlab.ui.control.Label
        Group4                        matlab.ui.control.EditField
        Group4Label                   matlab.ui.control.Label
        Group3                        matlab.ui.control.EditField
        Group3Label                   matlab.ui.control.Label
        Group6                        matlab.ui.control.EditField
        Group6Label                   matlab.ui.control.Label
        Group5                        matlab.ui.control.EditField
        Group5Label                   matlab.ui.control.Label
        Group2                        matlab.ui.control.EditField
        Group2Label                   matlab.ui.control.Label
        Group1                        matlab.ui.control.EditField
        Group1EditFieldLabel          matlab.ui.control.Label
        Numberofgroups                matlab.ui.control.NumericEditField
        NumberofgroupsEditFieldLabel  matlab.ui.control.Label
        Sheetname                     matlab.ui.control.EditField
        SheetnameforgroupingLabel     matlab.ui.control.Label
        SelectionofwellsPanel         matlab.ui.container.Panel
        HowtoproceedTextArea          matlab.ui.control.TextArea
        StartCalculationButton        matlab.ui.control.Button
        MinContEl                     matlab.ui.control.NumericEditField
        MinnbcontribElectrodesEditFieldLabel  matlab.ui.control.Label
        MinMFR                        matlab.ui.control.NumericEditField
        MinMFRonelectrodeinHzEditFieldLabel  matlab.ui.control.Label
        FileNb                        matlab.ui.control.NumericEditField
        FilenbusedforwellselectionzEditFieldLabel  matlab.ui.control.Label
        SelectcalculationsPanel       matlab.ui.container.Panel
        synchronicity                 matlab.ui.control.CheckBox
        Burstcalculation              matlab.ui.control.CheckBox
        Burstdetection                matlab.ui.control.CheckBox
        Spikecalculation              matlab.ui.control.CheckBox
        Spikedetection                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        savePath % Description
        groupNames 
        groupingFile
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCalculationButton
        function StartCalculationButtonPushed(app, event)
            spikedetection = app.Spikedetection.Value;
            spikecalc = app.Spikecalculation.Value;
            burstdetection = app.Burstdetection.Value;
            burstcalc = app.Burstcalculation.Value;
            synchronity = app.synchronicity.Value;
            file = app.FileNb.Value;
            minMFR = app.MinMFR.Value;
            MinContrEl = app.MinContEl.Value;            
            
            app.savePath = MEA_IndividualParameters_forGUI(spikedetection, spikecalc, burstdetection, burstcalc, synchronity,file, minMFR,...
                MinContrEl);
        end

        % Value changed function: Numberofgroups
        function NumberofgroupsValueChanged(app, event)
            value = event.Value;
            app.groupNames = cell(value,1);
            if value >= 1
                app.Group1.Visible = "on";
                app.Group1.Enable = "on";
                app.Group1.HandleVisibility = "on"; 
            else
                app.Group1.Visible = "off";
                app.Group1.Enable = "off";
                app.Group1.HandleVisibility = "off"; 
            end
            if value >= 2
                app.Group2.Visible = "on";
                app.Group2.Enable = "on";
                app.Group2.HandleVisibility = "on";
            else
                app.Group2.Visible = "off";
                app.Group2.Enable = "off";
                app.Group2.HandleVisibility = "off";
            end
            if value >= 3
                app.Group3.Visible = "on";
                app.Group3.Enable = "on";
                app.Group3.HandleVisibility = "on";
            else
                app.Group3.Visible = "off";
                app.Group3.Enable = "off";
                app.Group3.HandleVisibility = "off";
            end
            if value >= 4
                app.Group4.Visible = "on";
                app.Group4.Enable = "on";
                app.Group4.HandleVisibility = "on";
            else
                app.Group4.Visible = "off";
                app.Group4.Enable = "off";
                app.Group4.HandleVisibility = "off";
            end
            if value >= 5
                app.Group5.Visible = "on";
                app.Group5.Enable = "on";
                app.Group5.HandleVisibility = "on";
            else
                app.Group5.Visible = "off";
                app.Group5.Enable = "off";
                app.Group5.HandleVisibility = "off";
            end
            if value >= 6
                app.Group6.Visible = "on";
                app.Group6.Enable = "on";
                app.Group6.HandleVisibility = "on";
            else
                app.Group6.Visible = "off";
                app.Group6.Enable = "off";
                app.Group6.HandleVisibility = "off";
            end
            if value >= 7
                app.Group7.Visible = "on";
                app.Group7.Enable = "on";
                app.Group7.HandleVisibility = "on";
            else
                app.Group7.Visible = "off";
                app.Group7.Enable = "off";
                app.Group7.HandleVisibility = "off";
            end
            if value >= 8
                app.Group8.Visible = "on";
                app.Group8.Enable = "on";
                app.Group8.HandleVisibility = "on";
            else
                app.Group8.Visible = "off";
                app.Group8.Enable = "off";
                app.Group8.HandleVisibility = "off";
            end
            
            if value >= 9
                app.Group9.Visible = "on";
                app.Group9.Enable = "on";
                app.Group9.HandleVisibility = "on";
            else
                app.Group9.Visible = "off";
                app.Group9.Enable = "off";
                app.Group9.HandleVisibility = "off";
            end
            
            if value >= 10
                app.Group10.Visible = "on";
                app.Group10.Enable = "on";
                app.Group10.HandleVisibility = "on";
            else
                app.Group10.Visible = "off";
                app.Group10.Enable = "off";
                app.Group10.HandleVisibility = "off";
            end
            
            if value >= 11
                app.Group11.Visible = "on";
                app.Group11.Enable = "on";
                app.Group11.HandleVisibility = "on";
            else
                app.Group11.Visible = "off";
                app.Group11.Enable = "off";
                app.Group11.HandleVisibility = "off";
            end
            if value >= 12
                app.Group12.Visible = "on";
                app.Group12.Enable = "on";
                app.Group12.HandleVisibility = "on";
            else
                app.Group12.Visible = "off";
                app.Group12.Enable = "off";
                app.Group12.HandleVisibility = "off";
            end
        end

        % Button pushed function: SpikeAnalysisButton
        function SpikeAnalysisButtonPushed(app, event)
            names = app.groupNames;
            sheetname = app.Sheetname.Value;
            nbGroups = app.Numberofgroups.Value;
            %savepath = app.savePath;
            dataStruct = groupSpikeCalc_GUI(names, sheetname, nbGroups,app.groupingFile);
            dataStore = indivWellsPerGroup_GUI(dataStruct);
            if ~isempty(app.SheetnameBin.Value)
                timepoints = getTimepoints_GUI(app.SheetnameBin.Value);
                dataStore = groupingIndays_GUI(dataStore, timepoints);
            else
                dataStore = groupingIndays_GUI(dataStore);
            end 
            export2csv_indivWells_timeGrouped(dataStore)            
        end

        % Value changing function: Group1
        function Group1ValueChanging(app, event)
            changingValue = event.Value;
            app.groupNames{1,1} = changingValue;
        end

        % Value changed function: Group2
        function Group2ValueChanged(app, event)
            value = event.Value;
            app.groupNames{2,1} = value;
        end

        % Value changed function: Group5
        function Group5ValueChanged(app, event)
            value = app.Group5.Value;
            app.groupNames{5,1} = value;
        end

        % Value changed function: Group6
        function Group6ValueChanged(app, event)
            value = app.Group6.Value;
            app.groupNames{6,1} = value;
        end

        % Value changed function: Group3
        function Group3ValueChanged(app, event)
            value = app.Group3.Value;
            app.groupNames{3,1} = value;
        end

        % Value changed function: Group4
        function Group4ValueChanged(app, event)
            value = app.Group4.Value;
            app.groupNames{4,1} = value;
        end

        % Value changed function: Group7
        function Group7ValueChanged(app, event)
            value = app.Group7.Value;
            app.groupNames{7,1} = value;
        end

        % Value changed function: Group8
        function Group8ValueChanged(app, event)
            value = app.Group8.Value;
            app.groupNames{8,1} = value;
        end

        % Button pushed function: Loadgrouping
        function LoadgroupingPushed(app, event)
            [file, path] = uigetfile('.xlsx','select grouping list');
            app.groupingFile = fullfile(path,file);
        end

        % Value changed function: SheetnameBin
        function SheetnameBinValueChanged(app, event)
            %value = app.SheetnameBin.Value;
            
        end

        % Button pushed function: BurstAnalysisButton
        function BurstAnalysisButtonPushed(app, event)
            names = app.groupNames;
            sheetname = app.Sheetname.Value;
            nbGroups = app.Numberofgroups.Value;
            %savepath = app.savePath;
            dataStruct = groupBurstCalc_GUI(names, sheetname, nbGroups,app.groupingFile);
            dataStore = indivWellsPerGroup_GUI(dataStruct);
            if ~isempty(app.SheetnameBin.Value)
                timepoints = getTimepoints_GUI(app.SheetnameBin.Value);
                dataStore = groupingIndays_GUI(dataStore, timepoints);
            else
                dataStore = groupingIndays_GUI(dataStore);
            end 
            export2csv_indivWells_timeGrouped(dataStore)   
        end

        % Button pushed function: NetworkburstAnalysisButton
        function NetworkburstAnalysisButtonPushed(app, event)
            names = app.groupNames;
            sheetname = app.Sheetname.Value;
            nbGroups = app.Numberofgroups.Value;
            %savepath = app.savePath;
            dataStruct = groupNBCalc_GUI(names, sheetname, nbGroups,app.groupingFile);
            dataStore = indivWellsPerGroup_GUI(dataStruct,1);
            if ~isempty(app.SheetnameBin.Value)
                timepoints = getTimepoints_GUI(app.SheetnameBin.Value);
                dataStore = groupingIndays_GUI(dataStore, timepoints);
            else
                dataStore = groupingIndays_GUI(dataStore);
            end 
            export2csv_indivWells_timeGrouped(dataStore)   
        end

        % Button pushed function: ConnectivityButton
        function ConnectivityButtonPushed(app, event)
            names = app.groupNames;
            sheetname = app.Sheetname.Value;
            nbGroups = app.Numberofgroups.Value;
            %savepath = app.savePath;
            
            dataStruct = groupConnCalc_GUI(names, sheetname, nbGroups,app.groupingFile, filenames, pathname);
            dataStore = indivWellsPerGroup_GUI(dataStruct,4);
            if ~isempty(app.SheetnameBin.Value)
                timepoints = getTimepoints_GUI(app.SheetnameBin.Value);
                dataStore = groupingIndays_GUI(dataStore, timepoints);
            else
                dataStore = groupingIndays_GUI(dataStore);
            end 
            export2csv_indivWells_timeGrouped(dataStore)   
        end

        % Value changing function: Group9
        function Group9ValueChanging(app, event)
            changingValue = event.Value;
            app.groupNames{9,1} = changingValue;            
        end

        % Value changing function: Group10
        function Group10ValueChanging(app, event)
            changingValue = event.Value;
            app.groupNames{10,1} = changingValue;
        end

        % Value changing function: Group11
        function Group11ValueChanging(app, event)
            changingValue = event.Value;
            app.groupNames{11,1} = changingValue;
        end

        % Value changing function: Group12
        function Group12ValueChanging(app, event)
            changingValue = event.Value;
            app.groupNames{12,1} = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectcalculationsPanel = uipanel(app.UIFigure);
            app.SelectcalculationsPanel.Title = 'Select calculations';
            app.Spikedetection = uicheckbox(app.SelectcalculationsPanel);
            app.Spikedetection.Text = 'Spike detection';
            app.Spikedetection.Value = true;
            app.Spikecalculation = uicheckbox(app.SelectcalculationsPanel);
            app.Spikecalculation.Text = 'Spike calculation';
            app.Spikecalculation.Value = true;
            app.Burstdetection = uicheckbox(app.SelectcalculationsPanel);
            app.Burstdetection.Text = 'Burst detection';
            app.Burstdetection.Value = true;
            app.Burstcalculation = uicheckbox(app.SelectcalculationsPanel);
            app.Burstcalculation.Text = 'Burst calculation';
            app.Burstcalculation.Value = true;
            app.synchronicity = uicheckbox(app.SelectcalculationsPanel);
            app.synchronicity.Text = 'synchronicity';
            app.synchronicity.Value = true;
            app.SelectionofwellsPanel = uipanel(app.UIFigure);
            app.SelectionofwellsPanel.Title = 'Selection of wells';
            app.FilenbusedforwellselectionzEditFieldLabel = uilabel(app.SelectionofwellsPanel);
            app.FilenbusedforwellselectionzEditFieldLabel.Text = 'File nb used for well selection (z)';
            app.FileNb = uieditfield(app.SelectionofwellsPanel, 'numeric');
            app.FileNb.Limits = [1 Inf];
            app.FileNb.Value = 3;
            app.MinMFRonelectrodeinHzEditFieldLabel = uilabel(app.SelectionofwellsPanel);
            app.MinMFRonelectrodeinHzEditFieldLabel.Text = 'Min. MFR on electrode in Hz ';
            app.MinMFR = uieditfield(app.SelectionofwellsPanel, 'numeric');
            app.MinMFR.Limits = [0 Inf];
            app.MinMFR.Value = 0.1;
            app.MinnbcontribElectrodesEditFieldLabel = uilabel(app.SelectionofwellsPanel);
            app.MinnbcontribElectrodesEditFieldLabel.Text = 'Min. nb contrib. Electrodes';
            app.MinContEl = uieditfield(app.SelectionofwellsPanel, 'numeric');
            app.MinContEl.Limits = [1 Inf];
            app.MinContEl.ValueDisplayFormat = '%.0f';
            app.MinContEl.Value = 8;
            app.StartCalculationButton = uibutton(app.SelectionofwellsPanel, 'push');
            app.StartCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalculationButtonPushed, true);
            app.StartCalculationButton.Text = 'Start Calculation';
            app.HowtoproceedTextArea = uitextarea(app.SelectionofwellsPanel);
            app.HowtoproceedTextArea.Value = {'for each experimental bin file a .csv file is stored in your path.'};
            app.GroupingPanel = uipanel(app.UIFigure);
            app.GroupingPanel.Title = 'Grouping';
            app.SheetnameforgroupingLabel = uilabel(app.GroupingPanel);
            app.SheetnameforgroupingLabel.Text = 'Sheetname for grouping';
            app.Sheetname = uieditfield(app.GroupingPanel, 'text');
            app.NumberofgroupsEditFieldLabel = uilabel(app.GroupingPanel);
            app.NumberofgroupsEditFieldLabel.Text = 'Number of groups';
            app.Numberofgroups = uieditfield(app.GroupingPanel, 'numeric');
            app.Numberofgroups.ValueChangedFcn = createCallbackFcn(app, @NumberofgroupsValueChanged, true);
            app.Group1EditFieldLabel = uilabel(app.GroupingPanel);
            app.Group1EditFieldLabel.Text = 'Group 1';
            app.Group1 = uieditfield(app.GroupingPanel, 'text');
            app.Group1.ValueChangingFcn = createCallbackFcn(app, @Group1ValueChanging, true);
            app.Group1.Visible = 'off';
            app.Group2Label = uilabel(app.GroupingPanel);
            app.Group2Label.Text = 'Group 2';
            app.Group2 = uieditfield(app.GroupingPanel, 'text');
            app.Group2.ValueChangedFcn = createCallbackFcn(app, @Group2ValueChanged, true);
            app.Group2.Visible = 'off';
            app.Group5Label = uilabel(app.GroupingPanel);
            app.Group5Label.Text = 'Group 5';
            app.Group5 = uieditfield(app.GroupingPanel, 'text');
            app.Group5.ValueChangedFcn = createCallbackFcn(app, @Group5ValueChanged, true);
            app.Group5.Visible = 'off';
            app.Group6Label = uilabel(app.GroupingPanel);
            app.Group6Label.Text = 'Group 6';
            app.Group6 = uieditfield(app.GroupingPanel, 'text');
            app.Group6.ValueChangedFcn = createCallbackFcn(app, @Group6ValueChanged, true);
            app.Group6.Visible = 'off';
            app.Group3Label = uilabel(app.GroupingPanel);
            app.Group3Label.Text = 'Group 3';
            app.Group3 = uieditfield(app.GroupingPanel, 'text');
            app.Group3.ValueChangedFcn = createCallbackFcn(app, @Group3ValueChanged, true);
            app.Group3.Visible = 'off';
            app.Group4Label = uilabel(app.GroupingPanel);
            app.Group4Label.Text = 'Group 4';
            app.Group4 = uieditfield(app.GroupingPanel, 'text');
            app.Group4.ValueChangedFcn = createCallbackFcn(app, @Group4ValueChanged, true);
            app.Group4.Visible = 'off';
            app.Group7Label = uilabel(app.GroupingPanel);
            app.Group7Label.Text = 'Group 7';
            app.Group7 = uieditfield(app.GroupingPanel, 'text');
            app.Group7.ValueChangedFcn = createCallbackFcn(app, @Group7ValueChanged, true);
            app.Group7.Visible = 'off';
            app.Group8Label = uilabel(app.GroupingPanel);
            app.Group8Label.Text = 'Group 8';
            app.Group8 = uieditfield(app.GroupingPanel, 'text');
            app.Group8.ValueChangedFcn = createCallbackFcn(app, @Group8ValueChanged, true);
            app.Group8.Visible = 'off';
            app.Loadgrouping = uibutton(app.GroupingPanel, 'push');
            app.Loadgrouping.ButtonPushedFcn = createCallbackFcn(app, @LoadgroupingPushed, true);
            app.Loadgrouping.Text = 'Load grouping .csv';
            app.Group9Label = uilabel(app.GroupingPanel);
            app.Group9Label.Text = 'Group 9';
            app.Group9 = uieditfield(app.GroupingPanel, 'text');
            app.Group9.ValueChangingFcn = createCallbackFcn(app, @Group9ValueChanging, true);
            app.Group9.Visible = 'off';
            app.Group10Label = uilabel(app.GroupingPanel);
            app.Group10Label.Text = 'Group 10';
            app.Group10 = uieditfield(app.GroupingPanel, 'text');
            app.Group10.ValueChangingFcn = createCallbackFcn(app, @Group10ValueChanging, true);
            app.Group10.Visible = 'off';
            app.Group11Label = uilabel(app.GroupingPanel);
            app.Group11Label.Text = 'Group 11';
            app.Group11 = uieditfield(app.GroupingPanel, 'text');
            app.Group11.ValueChangingFcn = createCallbackFcn(app, @Group11ValueChanging, true);
            app.Group11.Visible = 'off';
            app.Group12Label = uilabel(app.GroupingPanel);
            app.Group12Label.Text = 'Group 12';
            app.Group12 = uieditfield(app.GroupingPanel, 'text');
            app.Group12.ValueChangingFcn = createCallbackFcn(app, @Group12ValueChanging, true);
            app.Group12.Visible = 'off';
            app.SpikeAnalysisButton = uibutton(app.UIFigure, 'push');
            app.SpikeAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SpikeAnalysisButtonPushed, true);
            app.SpikeAnalysisButton.Text = 'Spike Analysis';
            app.SheetnameforbinningLabel = uilabel(app.UIFigure);
            app.SheetnameforbinningLabel.Text = 'Sheetname for binning';
            app.SheetnameBin = uieditfield(app.UIFigure, 'text');
            app.SheetnameBin.ValueChangedFcn = createCallbackFcn(app, @SheetnameBinValueChanged, true);
            app.BurstAnalysisButton = uibutton(app.UIFigure, 'push');
            app.BurstAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BurstAnalysisButtonPushed, true);
            app.BurstAnalysisButton.Text = 'Burst Analysis';
            app.NetworkburstAnalysisButton = uibutton(app.UIFigure, 'push');
            app.NetworkburstAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkburstAnalysisButtonPushed, true);
            app.NetworkburstAnalysisButton.Text = 'Networkburst Analysis';
            app.ConnectivityButton = uibutton(app.UIFigure, 'push');
            app.ConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityButtonPushed, true);
            app.ConnectivityButton.Text = 'Connectivity ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_indivParameters_basicAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end